classdef fNIRSInputArgsApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        NewCOMPortDropDownLabel   matlab.ui.control.Label
        NewCOMPortDropDown        matlab.ui.control.DropDown
        UpdateArgumentsButton     matlab.ui.control.Button
        CurrentConfigPanel        matlab.ui.container.Panel
        CFGBrowseButton           matlab.ui.control.Button
        ConfigFileEditFieldLabel  matlab.ui.control.Label
        ConfigFileEditField       matlab.ui.control.EditField
        devIDEditFieldLabel       matlab.ui.control.Label
        devIDEditField            matlab.ui.control.EditField
        COMPortEditFieldLabel     matlab.ui.control.Label
        COMPortEditField          matlab.ui.control.EditField
        LoadButton                matlab.ui.control.Button
        ContinueButton            matlab.ui.control.Button
    end

    
    properties (Access = private)
        CallingApp % Main fNIRSapp
        DefaultCFG = 'fNIRS.cfg' % Default CFG file to read
        DeviceInfo % Description
        Environment % Description
        Config % Contents of the config file
    end
    
    properties (Access = public)
        COM % Stores selected serial port
    end
    
    methods (Access = private)


        function CFGLoader(app,file)
            if exist(file,'file')
                app.ConfigFileEditField.Value = file;
                if strcmp(file(end-3:end),'.cfg')
                    app.Config = load(file, '-mat');
                end
                app.devIDEditField.Value = app.Config.devinfo.devID;
                app.COMPortEditField.Value = app.Config.devinfo.commPort;
                
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            app.UIFigure.Visible='off';
            movegui(app.UIFigure,'center')
            
            % Store main app object
            app.CallingApp = mainapp;
            
            app.NewCOMPortDropDown.Visible = 'off';
            app.UpdateArgumentsButton.Visible = 'off';
            
            % Collect startup arguments
            app.NewCOMPortDropDown.Items = serialportlist;
            
            CFGLoader(app,app.DefaultCFG)
            
            app.NewCOMPortDropDown.Visible = 'on';
            app.UpdateArgumentsButton.Visible = 'on';


        end

        % Button pushed function: CFGBrowseButton
        function CFGBrowseButtonPushed(app, event)
            app.ConfigFileEditField.Value = uigetfile({'*.cfg', 'Hardware configuration file (*.cfg)'});
            if ~app.ConfigFileEditField.Value
                disp('No file selected, defaulting to fNIRS.cfg')
                app.ConfigFileEditField.Value='fNIRS.cfg';
            end
        end

        % Button pushed function: UpdateArgumentsButton
        function UpdateArgumentsButtonPushed(app, event)
            app.Config.devinfo.commPort = app.NewCOMPortDropDown.Value;
            app.COMPortEditField.Value = app.Config.devinfo.commPort;
            save('fNIRS.cfg',app.Config)
        end

        % Button pushed function: ContinueButton
        function ContinueButtonPushed(app, event)
            ninjaGUI(app.CallingApp, app.ConfigFileEditField.Value);
            
            delete(app)
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete(app)
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            CFGLoader(app,app.ConfigFileEditField.Value);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.NewCOMPortDropDownLabel = uilabel(app.UIFigure);
            app.NewCOMPortDropDownLabel.Text = 'New COM Port';
            app.NewCOMPortDropDown = uidropdown(app.UIFigure);
            app.NewCOMPortDropDown.Items = {'', '', '', ''};
            app.NewCOMPortDropDown.Value = '';
            app.UpdateArgumentsButton = uibutton(app.UIFigure, 'push');
            app.UpdateArgumentsButton.ButtonPushedFcn = createCallbackFcn(app, @UpdateArgumentsButtonPushed, true);
            app.UpdateArgumentsButton.Text = 'Update Arguments';
            app.CurrentConfigPanel = uipanel(app.UIFigure);
            app.CurrentConfigPanel.Title = 'Current Config';
            app.CFGBrowseButton = uibutton(app.CurrentConfigPanel, 'push');
            app.CFGBrowseButton.ButtonPushedFcn = createCallbackFcn(app, @CFGBrowseButtonPushed, true);
            app.CFGBrowseButton.Text = '...';
            app.ConfigFileEditFieldLabel = uilabel(app.CurrentConfigPanel);
            app.ConfigFileEditFieldLabel.Text = 'Config File';
            app.ConfigFileEditField = uieditfield(app.CurrentConfigPanel, 'text');
            app.devIDEditFieldLabel = uilabel(app.CurrentConfigPanel);
            app.devIDEditFieldLabel.Text = 'devID';
            app.devIDEditField = uieditfield(app.CurrentConfigPanel, 'text');
            app.devIDEditField.Editable = 'off';
            app.COMPortEditFieldLabel = uilabel(app.CurrentConfigPanel);
            app.COMPortEditFieldLabel.Text = 'COM Port';
            app.COMPortEditField = uieditfield(app.CurrentConfigPanel, 'text');
            app.COMPortEditField.Editable = 'off';
            app.LoadButton = uibutton(app.CurrentConfigPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.ContinueButton = uibutton(app.UIFigure, 'push');
            app.ContinueButton.ButtonPushedFcn = createCallbackFcn(app, @ContinueButtonPushed, true);
            app.ContinueButton.Text = 'Continue';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fNIRSInputArgsApp(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end